classdef GUI_Acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcquisitionUI               matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        NewDirectoryMenu            matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        GoliveButton                matlab.ui.control.StateButton
        Plot3_Yaxis                 matlab.ui.control.DropDown
        Plot3_Xaxis                 matlab.ui.control.DropDown
        Panel_3                     matlab.ui.container.Panel
        SampleName                  matlab.ui.control.DropDown
        SpikeInNormalizationPanel   matlab.ui.container.Panel
        SpikeinDiameternmEditField  matlab.ui.control.NumericEditField
        SpikeinDiameternmEditFieldLabel  matlab.ui.control.Label
        SpikeInSwitch               matlab.ui.control.Switch
        Plot2_Yaxis                 matlab.ui.control.DropDown
        Plot2_Xaxis                 matlab.ui.control.DropDown
        Plot1_Yaxis                 matlab.ui.control.DropDown
        Plot1_Xaxis                 matlab.ui.control.DropDown
        Panel_2                     matlab.ui.container.Panel
        CVTimePlot                  matlab.ui.control.UIAxes
        DiamHist                    matlab.ui.control.UIAxes
        S2NTT_Diam                  matlab.ui.control.UIAxes
        DiamTTimePlot               matlab.ui.control.UIAxes
        TTTimePlot                  matlab.ui.control.UIAxes
        TimeStatPlot                matlab.ui.control.UIAxes
        DiamTimePlot                matlab.ui.control.UIAxes
        GeneralContextMenu          matlab.ui.container.ContextMenu
        ForceUpdateMenu             matlab.ui.container.Menu
        ColormapMenu                matlab.ui.container.Menu
        jetMenu                     matlab.ui.container.Menu
        turboMenu                   matlab.ui.container.Menu
        hotMenu                     matlab.ui.container.Menu
        ColorscaleMenu              matlab.ui.container.Menu
        linearMenu                  matlab.ui.container.Menu
        logMenu                     matlab.ui.container.Menu
        GridsMenu                   matlab.ui.container.Menu
        XMajorMenu                  matlab.ui.container.Menu
        XMinorMenu                  matlab.ui.container.Menu
        YMajorMenu                  matlab.ui.container.Menu
        YMinorMenu                  matlab.ui.container.Menu
        ResolutionMenu              matlab.ui.container.Menu
        ResMenu_1                   matlab.ui.container.Menu
        ResMenu_2                   matlab.ui.container.Menu
        ResMenu_3                   matlab.ui.container.Menu
        RemovenoiseMenu             matlab.ui.container.Menu
        ContextMenu2                matlab.ui.container.ContextMenu
        ForceUpdateMenu_2           matlab.ui.container.Menu
        StatsMenu                   matlab.ui.container.Menu
        TotalEventsMenu             matlab.ui.container.Menu
        MeanTotalEventsMenu         matlab.ui.container.Menu
        AcqVolumeMenu               matlab.ui.container.Menu
        MeanAcqVolumeMenu           matlab.ui.container.Menu
    end


    properties (Access = public)
        LiveAcquisition = [];
        myTimer = [];
        Resolution = 256;
        TimeMax = 100;
        TTimeMax = 100;
        DiamMax = 400;
        TimeEdges = linspace(0,100, 256)
        TTimeEdges = linspace(0,100, 256)
        DiamEdges = linspace(0,400, 256)
        S2NTTEdges = logspace(-2,2, 256);
        Colormap = 'jet';
        Colorscaling = 'linear';
        PlotSelection 
    end

    methods (Access = private)


        function UpdateAllPlots(app)
            %             UpdatePlottingPrefs(app)
            Live_File_Acquisition(app)
        end

        function deselectRsolutionOptions(app)
            app.ResMenu_1.Checked = 'off';
            app.ResMenu_2.Checked = 'off';
            app.ResMenu_3.Checked = 'off';
        end

        function deselectColormapOptions(app)
            app.jetMenu.Checked = 'off';
            app.turboMenu.Checked = 'off';
            app.hotMenu.Checked = 'off';
        end
    end

    methods (Access = public)

        function UpdatePlottingPrefs(app)

            app.TimeEdges = linspace(0,app.TimeMax, app.Resolution);
            app.TTimeEdges = linspace(0,app.TTimeMax, app.Resolution);
            app.DiamEdges = linspace(0,app.DiamMax, app.Resolution);
            app.S2NTTEdges = logspace(-2,2, app.Resolution);

            % time vs. diameter plot preferences
            app.DiamTimePlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            app.DiamTimePlot.YLim = [min(app.DiamEdges),max(app.DiamEdges)];
            app.DiamTimePlot.XMinorTick = 'on';
            app.DiamTimePlot.XAxis.MinorTickValues = 0:10:max(app.TimeEdges);
            xticks(app.DiamTimePlot,'auto')
            yticks(app.DiamTimePlot,'auto')
            app.DiamTimePlot.Toolbar.Visible = 'off';
            app.DiamTimePlot.XGrid = app.XMajorMenu.Checked;
            app.DiamTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.DiamTimePlot.YGrid = app.YMajorMenu.Checked;
            app.DiamTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % transit time vs. diameter plot preferences
            app.DiamTTimePlot.XLim = [min(app.TTimeEdges),max(app.TTimeEdges)];
            app.DiamTTimePlot.YLim = [min(app.DiamEdges),max(app.DiamEdges)];
            xticks(app.DiamTTimePlot,'auto')
            yticks(app.DiamTTimePlot,'auto')
            app.DiamTTimePlot.Toolbar.Visible = 'off';
            app.DiamTTimePlot.XGrid = app.XMajorMenu.Checked;
            app.DiamTTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.DiamTTimePlot.YGrid = app.YMajorMenu.Checked;
            app.DiamTTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % time vs. stat
            app.TimeStatPlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            ylim(app.TimeStatPlot,'auto');
            xticks(app.TimeStatPlot,'auto')
            yticks(app.TimeStatPlot,'auto')
            app.TimeStatPlot.Toolbar.Visible = 'off';
            app.TimeStatPlot.XGrid = app.XMajorMenu.Checked;
            app.TimeStatPlot.XMinorGrid = app.XMinorMenu.Checked;
            app.TimeStatPlot.YGrid = app.YMajorMenu.Checked;
            app.TimeStatPlot.YMinorGrid = app.YMinorMenu.Checked;

            % time vs. transit time
            app.TTTimePlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            ylim(app.TTTimePlot,'auto');
            xticks(app.TTTimePlot,'auto')
            yticks(app.TTTimePlot,'auto')
            app.TTTimePlot.Toolbar.Visible = 'off';
            app.TTTimePlot.XGrid = app.XMajorMenu.Checked;
            app.TTTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.TTTimePlot.YGrid = app.YMajorMenu.Checked;
            app.TTTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % time vs. % CV
            app.CVTimePlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            ylim(app.CVTimePlot,'auto');
            xticks(app.CVTimePlot,'auto')
            yticks(app.CVTimePlot,'auto')
            app.CVTimePlot.Toolbar.Visible = 'off';
            app.CVTimePlot.XGrid = app.XMajorMenu.Checked;
            app.CVTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.CVTimePlot.YGrid = app.YMajorMenu.Checked;
            app.CVTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % SN/TT vs. % diam
            app.S2NTT_Diam.XLim = [min(app.S2NTTEdges),max(app.S2NTTEdges)];
            ylim(app.S2NTT_Diam,'auto');
            xticks(app.S2NTT_Diam,'auto')
            yticks(app.S2NTT_Diam,'auto')
            app.S2NTT_Diam.Toolbar.Visible = 'off';
            app.S2NTT_Diam.XGrid = app.XMajorMenu.Checked;
            app.S2NTT_Diam.XMinorGrid = app.XMinorMenu.Checked;
            app.S2NTT_Diam.YGrid = app.YMajorMenu.Checked;
            app.S2NTT_Diam.YMinorGrid = app.YMinorMenu.Checked;

            %  diam hist
            app.DiamHist.XLim = [min(app.DiamEdges),max(app.DiamEdges)];
            ylim(app.DiamHist,'auto');
            xticks(app.DiamHist,'auto')
            yticks(app.DiamHist,'auto')
            app.DiamHist.Toolbar.Visible = 'off';
            app.DiamHist.XGrid = app.XMajorMenu.Checked;
            app.DiamHist.XMinorGrid = app.XMinorMenu.Checked;
            app.DiamHist.YGrid = app.YMajorMenu.Checked;
            app.DiamHist.YMinorGrid = app.YMinorMenu.Checked;

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LiveAcquisition = true;
            app.myTimer = timer('Name','MyTimer',               ...
                'Period',10,                 ...
                'StartDelay',0,                 ...
                'TasksToExecute',inf,           ...
                'ExecutionMode','fixedSpacing', ...
                'StartFcn',@(~,~)Live_File_Acquisition(app),...
                'TimerFcn',@(~,~)Live_File_Acquisition(app));
        end

        % Callback function
        function testButtonPushed(app, event)
            UpdateAllPlots(app)
        end

        % Value changed function: SampleName
        function SampleNameValueChanged(app, event)
            UpdateAllPlots(app)
        end

        % Menu selected function: ResMenu_1
        function ResMenu_1Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_1.Checked = "on";

            app.Resolution = 128;
            UpdateAllPlots(app)
        end

        % Menu selected function: ResMenu_2
        function ResMenu_2Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_2.Checked = "on";
            app.Resolution = 256;
            UpdateAllPlots(app)
        end

        % Menu selected function: ResMenu_3
        function ResMenu_3Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_3.Checked = "on";
            app.Resolution = 512;
            UpdateAllPlots(app)
        end

        % Menu selected function: ForceUpdateMenu, ForceUpdateMenu_2
        function ForceUpdateMenuSelected(app, event)
            UpdateAllPlots(app)
        end

        % Menu selected function: jetMenu
        function jetMenuSelected(app, event)
            app.Colormap = 'jet';
            deselectColormapOptions(app)
            app.jetMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: turboMenu
        function turboMenuSelected(app, event)
            app.Colormap = 'turbo';
            deselectColormapOptions(app)
            app.turboMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: hotMenu
        function hotMenuSelected(app, event)
            app.Colormap = 'hot';
            deselectColormapOptions(app)
            app.hotMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: linearMenu
        function linearMenuSelected(app, event)
            app.Colorscaling = 'linear';
            app.logMenu.Checked = 'off';
            app.linearMenu.Checked = 'on';
            UpdateAllPlots(app)
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            app.Colorscaling = 'log';
            app.logMenu.Checked = 'on';
            app.linearMenu.Checked = 'off';
            UpdateAllPlots(app)
        end

        % Menu selected function: XMajorMenu
        function XMajorMenuSelected(app, event)
            switch app.XMajorMenu.Checked
                case 'on'
                    app.XMajorMenu.Checked = 'off';
                case 'off'
                    app.XMajorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: XMinorMenu
        function XMinorMenuSelected(app, event)
            switch app.XMinorMenu.Checked
                case 'on'
                    app.XMinorMenu.Checked = 'off';
                case 'off'
                    app.XMinorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: YMajorMenu
        function YMajorMenuSelected(app, event)
            switch app.YMajorMenu.Checked
                case 'on'
                    app.YMajorMenu.Checked = 'off';
                case 'off'
                    app.YMajorMenu.Checked = 'on';
            end
            UpdateAllPlots(app)
        end

        % Menu selected function: YMinorMenu
        function YMinorMenuSelected(app, event)
            switch app.YMinorMenu.Checked
                case 'on'
                    app.YMinorMenu.Checked = 'off';
                case 'off'
                    app.YMinorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: AcqVolumeMenu
        function AcqVolumeMenuSelected(app, event)
            switch app.AcqVolumeMenu.Checked
                case 'on'
                    app.AcqVolumeMenu.Checked = 'off';
                case 'off'
                    app.AcqVolumeMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: MeanTotalEventsMenu
        function MeanTotalEventsMenuSelected(app, event)
            switch app.MeanTotalEventsMenu.Checked
                case 'on'
                    app.MeanTotalEventsMenu.Checked = 'off';
                case 'off'
                    app.MeanTotalEventsMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: TotalEventsMenu
        function TotalEventsMenuSelected(app, event)
            switch app.TotalEventsMenu.Checked
                case 'on'
                    app.TotalEventsMenu.Checked = 'off';
                case 'off'
                    app.TotalEventsMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: MeanAcqVolumeMenu
        function MeanAcqVolumeMenuSelected(app, event)
             switch app.MeanAcqVolumeMenu.Checked
                case 'on'
                    app.MeanAcqVolumeMenu.Checked = 'off';
                case 'off'
                    app.MeanAcqVolumeMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Value changed function: GoliveButton
        function GoliveButtonValueChanged(app, event)
            switch app.myTimer.Running
                case 'off'
                    app.GoliveButton.BackgroundColor = [0.35,0.72,0.35];
                    app.GoliveButton.Text = 'Stop';
                    app.GoliveButton.Value = 1;
                    drawnow
                    start(app.myTimer)
                case 'on'
                    app.GoliveButton.BackgroundColor = [0.72,0.35,0.35];
                    app.GoliveButton.Text = 'Go live';
                    app.GoliveButton.Value = 0;
                    drawnow
                    stop(app.myTimer)
            end

        end

        % Value changed function: SpikeInSwitch
        function SpikeInSwitchValueChanged(app, event)
             UpdateAllPlots(app)
        end

        % Value changed function: SpikeinDiameternmEditField
        function SpikeinDiameternmEditFieldValueChanged(app, event)
             UpdateAllPlots(app)
        end

        % Menu selected function: RemovenoiseMenu
        function RemovenoiseMenuSelected(app, event)
            switch app.RemovenoiseMenu.Checked
                case "off"
            app.RemovenoiseMenu.Checked = "on";
                case "on"
              app.RemovenoiseMenu.Checked = "off";
            end
            UpdateAllPlots(app)
        end

        % Menu selected function: NewDirectoryMenu
        function NewDirectoryMenuSelected(app, event)

            last_dir = getprefRPSPASS('RPSPASS','last_dir');

            if isfolder(last_dir)
                selpath = uigetdir(last_dir);
            else
                selpath = uigetdir();
            end

            if selpath == 0
            else
                setprefRPSPASS('RPSPASS','last_dir',selpath)
                setprefRPSPASS('RPSPASS','acquisition_dir',selpath)
            end
        end

        % Value changed function: Plot1_Xaxis
        function Plot1_XaxisValueChanged(app, event)
           UpdateAllPlots(app)
            
        end

        % Value changed function: Plot1_Yaxis
        function Plot1_YaxisValueChanged(app, event)
            UpdateAllPlots(app)
            
        end

        % Value changed function: Plot2_Xaxis
        function Plot2_XaxisValueChanged(app, event)
            UpdateAllPlots(app)
            
        end

        % Value changed function: Plot3_Yaxis
        function Plot3_YaxisValueChanged(app, event)
            UpdateAllPlots(app)
            
        end

        % Value changed function: Plot3_Xaxis
        function Plot3_XaxisValueChanged(app, event)
            UpdateAllPlots(app)
            
        end

        % Value changed function: Plot2_Yaxis
        function Plot2_YaxisValueChanged(app, event)
            UpdateAllPlots(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AcquisitionUI = uifigure('Visible', 'off');
            app.AcquisitionUI.Name = 'RPSPASS Live Acquisition';
            app.AcquisitionUI.Icon = fullfile(pathToMLAPP, 'HTML', 'Images', 'RPS Logo.jpg');
            app.Menu = uimenu(app.AcquisitionUI);
            app.Menu.Text = 'Menu';
            app.NewDirectoryMenu = uimenu(app.Menu);
            app.NewDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @NewDirectoryMenuSelected, true);
            app.NewDirectoryMenu.Text = 'New Directory';
            app.Panel = uipanel(app.AcquisitionUI);
            app.GridLayout = uigridlayout(app.Panel);
            app.DiamTimePlot = uiaxes(app.GridLayout);
            xlabel(app.DiamTimePlot, 'Time (secs)')
            ylabel(app.DiamTimePlot, 'Diameter (nm)')
            app.DiamTimePlot.YLim = [0 1];
            app.DiamTimePlot.XTick = [];
            app.DiamTimePlot.YTick = [];
            app.TimeStatPlot = uiaxes(app.GridLayout);
            xlabel(app.TimeStatPlot, 'Time (secs)')
            ylabel(app.TimeStatPlot, 'Stat')
            app.TimeStatPlot.XLim = [0 100];
            app.TimeStatPlot.YLim = [0 400];
            app.TimeStatPlot.XTick = [];
            app.TimeStatPlot.YTick = [];
            app.TTTimePlot = uiaxes(app.GridLayout);
            xlabel(app.TTTimePlot, 'Time (secs)')
            ylabel(app.TTTimePlot, 'Spike-In Transit time (µs)')
            app.TTTimePlot.YLim = [0 1];
            app.TTTimePlot.XTick = [];
            app.TTTimePlot.YTick = [];
            app.DiamTTimePlot = uiaxes(app.GridLayout);
            xlabel(app.DiamTTimePlot, 'Transit Time (µs)')
            ylabel(app.DiamTTimePlot, 'Diameter (nm)')
            app.DiamTTimePlot.XLim = [0 100];
            app.DiamTTimePlot.YLim = [0 400];
            app.DiamTTimePlot.XTick = [];
            app.DiamTTimePlot.YTick = [];
            app.S2NTT_Diam = uiaxes(app.GridLayout);
            xlabel(app.S2NTT_Diam, 'S2N/Transit Time (µs)')
            ylabel(app.S2NTT_Diam, 'Diameter (nm)')
            app.S2NTT_Diam.XLim = [0 100];
            app.S2NTT_Diam.YLim = [0 400];
            app.S2NTT_Diam.XTick = [];
            app.S2NTT_Diam.YTick = [];
            app.DiamHist = uiaxes(app.GridLayout);
            xlabel(app.DiamHist, 'Diameter (nm)')
            ylabel(app.DiamHist, 'Count')
            app.DiamHist.XLim = [0 100];
            app.DiamHist.YLim = [0 400];
            app.DiamHist.XTick = [];
            app.DiamHist.YTick = [];
            app.CVTimePlot = uiaxes(app.GridLayout);
            xlabel(app.CVTimePlot, 'Time (secs)')
            ylabel(app.CVTimePlot, 'Spike-In CV (%)')
            app.CVTimePlot.YLim = [0 1];
            app.CVTimePlot.XTick = [];
            app.CVTimePlot.YTick = [];
            app.Panel_2 = uipanel(app.GridLayout);
            app.Plot1_Xaxis = uidropdown(app.GridLayout);
            app.Plot1_Xaxis.Items = {};
            app.Plot1_Xaxis.ValueChangedFcn = createCallbackFcn(app, @Plot1_XaxisValueChanged, true);
            app.Plot1_Xaxis.Value = {};
            app.Plot1_Yaxis = uidropdown(app.GridLayout);
            app.Plot1_Yaxis.Items = {};
            app.Plot1_Yaxis.ValueChangedFcn = createCallbackFcn(app, @Plot1_YaxisValueChanged, true);
            app.Plot1_Yaxis.Value = {};
            app.Plot2_Xaxis = uidropdown(app.GridLayout);
            app.Plot2_Xaxis.Items = {};
            app.Plot2_Xaxis.ValueChangedFcn = createCallbackFcn(app, @Plot2_XaxisValueChanged, true);
            app.Plot2_Xaxis.Value = {};
            app.Plot2_Yaxis = uidropdown(app.GridLayout);
            app.Plot2_Yaxis.Items = {};
            app.Plot2_Yaxis.ValueChangedFcn = createCallbackFcn(app, @Plot2_YaxisValueChanged, true);
            app.Plot2_Yaxis.Value = {};
            app.Panel_3 = uipanel(app.GridLayout);
            app.SpikeInNormalizationPanel = uipanel(app.Panel_3);
            app.SpikeInNormalizationPanel.Title = 'Spike-In Normalization';
            app.SpikeInSwitch = uiswitch(app.SpikeInNormalizationPanel, 'slider');
            app.SpikeInSwitch.ValueChangedFcn = createCallbackFcn(app, @SpikeInSwitchValueChanged, true);
            app.SpikeInSwitch.Value = 'On';
            app.SpikeinDiameternmEditFieldLabel = uilabel(app.SpikeInNormalizationPanel);
            app.SpikeinDiameternmEditFieldLabel.Text = 'Spike-in Diameter (nm)';
            app.SpikeinDiameternmEditField = uieditfield(app.SpikeInNormalizationPanel, 'numeric');
            app.SpikeinDiameternmEditField.RoundFractionalValues = 'on';
            app.SpikeinDiameternmEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeinDiameternmEditFieldValueChanged, true);
            app.SpikeinDiameternmEditField.Value = 240;
            app.SampleName = uidropdown(app.Panel_3);
            app.SampleName.Items = {};
            app.SampleName.ValueChangedFcn = createCallbackFcn(app, @SampleNameValueChanged, true);
            app.SampleName.Value = {};
            app.Plot3_Xaxis = uidropdown(app.GridLayout);
            app.Plot3_Xaxis.Items = {};
            app.Plot3_Xaxis.ValueChangedFcn = createCallbackFcn(app, @Plot3_XaxisValueChanged, true);
            app.Plot3_Xaxis.Value = {};
            app.Plot3_Yaxis = uidropdown(app.GridLayout);
            app.Plot3_Yaxis.Items = {};
            app.Plot3_Yaxis.ValueChangedFcn = createCallbackFcn(app, @Plot3_YaxisValueChanged, true);
            app.Plot3_Yaxis.Value = {};
            app.GoliveButton = uibutton(app.GridLayout, 'state');
            app.GoliveButton.ValueChangedFcn = createCallbackFcn(app, @GoliveButtonValueChanged, true);
            app.GoliveButton.Text = 'Go live';
            app.GoliveButton.Value = true;
            app.GeneralContextMenu = uicontextmenu(app.AcquisitionUI);
            app.ForceUpdateMenu = uimenu(app.GeneralContextMenu);
            app.ForceUpdateMenu.MenuSelectedFcn = createCallbackFcn(app, @ForceUpdateMenuSelected, true);
            app.ForceUpdateMenu.Text = 'Force Update';
            app.ColormapMenu = uimenu(app.GeneralContextMenu);
            app.ColormapMenu.Text = 'Colormap';
            app.jetMenu = uimenu(app.ColormapMenu);
            app.jetMenu.MenuSelectedFcn = createCallbackFcn(app, @jetMenuSelected, true);
            app.jetMenu.Checked = 'on';
            app.jetMenu.Text = 'jet';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @turboMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hotMenu = uimenu(app.ColormapMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @hotMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.ColorscaleMenu = uimenu(app.GeneralContextMenu);
            app.ColorscaleMenu.Text = 'Colorscale';
            app.linearMenu = uimenu(app.ColorscaleMenu);
            app.linearMenu.MenuSelectedFcn = createCallbackFcn(app, @linearMenuSelected, true);
            app.linearMenu.Checked = 'on';
            app.linearMenu.Text = 'linear';
            app.logMenu = uimenu(app.ColorscaleMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.GridsMenu = uimenu(app.GeneralContextMenu);
            app.GridsMenu.Text = 'Grids';
            app.XMajorMenu = uimenu(app.GridsMenu);
            app.XMajorMenu.MenuSelectedFcn = createCallbackFcn(app, @XMajorMenuSelected, true);
            app.XMajorMenu.Checked = 'on';
            app.XMajorMenu.Text = 'X Major';
            app.XMinorMenu = uimenu(app.GridsMenu);
            app.XMinorMenu.MenuSelectedFcn = createCallbackFcn(app, @XMinorMenuSelected, true);
            app.XMinorMenu.Checked = 'on';
            app.XMinorMenu.Text = 'X Minor';
            app.YMajorMenu = uimenu(app.GridsMenu);
            app.YMajorMenu.MenuSelectedFcn = createCallbackFcn(app, @YMajorMenuSelected, true);
            app.YMajorMenu.Checked = 'on';
            app.YMajorMenu.Text = 'Y Major';
            app.YMinorMenu = uimenu(app.GridsMenu);
            app.YMinorMenu.MenuSelectedFcn = createCallbackFcn(app, @YMinorMenuSelected, true);
            app.YMinorMenu.Checked = 'on';
            app.YMinorMenu.Text = 'Y Minor';
            app.ResolutionMenu = uimenu(app.GeneralContextMenu);
            app.ResolutionMenu.Text = 'Resolution';
            app.ResMenu_1 = uimenu(app.ResolutionMenu);
            app.ResMenu_1.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_1Selected, true);
            app.ResMenu_1.Text = '128';
            app.ResMenu_2 = uimenu(app.ResolutionMenu);
            app.ResMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_2Selected, true);
            app.ResMenu_2.Checked = 'on';
            app.ResMenu_2.Text = '256';
            app.ResMenu_3 = uimenu(app.ResolutionMenu);
            app.ResMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_3Selected, true);
            app.ResMenu_3.Text = '512';
            app.RemovenoiseMenu = uimenu(app.GeneralContextMenu);
            app.RemovenoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovenoiseMenuSelected, true);
            app.RemovenoiseMenu.Text = 'Remove noise';
            % Assign app.GeneralContextMenu
            app.AcquisitionUI.ContextMenu = app.GeneralContextMenu;
            app.Panel_2.ContextMenu = app.GeneralContextMenu;
            app.SampleName.ContextMenu = app.GeneralContextMenu;
            app.DiamTimePlot.ContextMenu = app.GeneralContextMenu;
            app.TTTimePlot.ContextMenu = app.GeneralContextMenu;
            app.DiamTTimePlot.ContextMenu = app.GeneralContextMenu;
            app.S2NTT_Diam.ContextMenu = app.GeneralContextMenu;
            app.DiamHist.ContextMenu = app.GeneralContextMenu;
            app.CVTimePlot.ContextMenu = app.GeneralContextMenu;
            app.Panel_3.ContextMenu = app.GeneralContextMenu;
            app.ContextMenu2 = uicontextmenu(app.AcquisitionUI);
            app.ForceUpdateMenu_2 = uimenu(app.ContextMenu2);
            app.ForceUpdateMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ForceUpdateMenuSelected, true);
            app.ForceUpdateMenu_2.Text = 'Force Update';
            app.StatsMenu = uimenu(app.ContextMenu2);
            app.StatsMenu.Text = 'Stats';
            app.TotalEventsMenu = uimenu(app.StatsMenu);
            app.TotalEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @TotalEventsMenuSelected, true);
            app.TotalEventsMenu.Text = 'Total Events';
            app.MeanTotalEventsMenu = uimenu(app.StatsMenu);
            app.MeanTotalEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanTotalEventsMenuSelected, true);
            app.MeanTotalEventsMenu.Text = 'Mean Total Events';
            app.AcqVolumeMenu = uimenu(app.StatsMenu);
            app.AcqVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcqVolumeMenuSelected, true);
            app.AcqVolumeMenu.Text = 'Acq Volume';
            app.MeanAcqVolumeMenu = uimenu(app.StatsMenu);
            app.MeanAcqVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanAcqVolumeMenuSelected, true);
            app.MeanAcqVolumeMenu.Text = 'Mean Acq Volume';
            % Assign app.ContextMenu2
            app.TimeStatPlot.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.AcquisitionUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Acquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AcquisitionUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AcquisitionUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcquisitionUI)
        end
    end
end