classdef GUI_Master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPSPASS          matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        PreferencesMenu  matlab.ui.container.Menu
        ExportMenu       matlab.ui.container.Menu
        ErrorlogMenu     matlab.ui.container.Menu
        ModeMenu         matlab.ui.container.Menu
        UserMenu         matlab.ui.container.Menu
        DeveloperMenu    matlab.ui.container.Menu
        HTML             matlab.ui.control.HTML
    end


    properties (Access = private)

    end

    properties (Access = public)
        SpikeInUsed
        SpikeInDiam
        SpikeInConc
        Version
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Version = "1.0.2";
            RPSPASS_Preference(app)
            softwareUpdate(app)

        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)

            if app.UserMenu.Checked == true
                try
                    Output = jsondecode(app.HTML.Data);
                    switch Output.Response
                        case 'Live'

                            last_dir = getprefRPSPASS('RPSPASS','last_dir');

                            if isfolder(last_dir)
                                selpath = uigetdir(last_dir);
                            else
                                selpath = uigetdir();
                            end

                            if selpath == 0
                            else
                                setprefRPSPASS('RPSPASS','last_dir',selpath)
                                setprefRPSPASS('RPSPASS','acquisition_dir',selpath)
                                GUI_Acquisition
                            end

                        case 'Submit'

                            [app] = getSpikeInInfo(app, Output);

                            last_dir = getprefRPSPASS('RPSPASS','last_dir');

                            if isfolder(last_dir)
                                selpath = uigetdir(last_dir);
                            else
                                selpath = uigetdir();
                            end

                            if selpath == 0
                            else
                                setprefRPSPASS('RPSPASS','last_dir',selpath)
                            end

                            [status, message]=ImportFiles(app, selpath,[]);
                            if status == true
                                app.HTML.HTMLSource = 'Success.html';
                            elseif status == false
                                app.HTML.HTMLSource = 'Unsuccessful.html';
                            end
                        case 'Restart'
                            app.HTML.HTMLSource = 'StartMenu.html';
                        case 'Save'
                            app.HTML.HTMLSource = 'StartMenu.html';
                            pause(0.1)
                            PrefOverwrite(app)
                        case 'Reset'
                            rmprefRPSPASS('RPSPASS')
                            RPSPASS_Preference(app)
                            pref = getprefRPSPASS('RPSPASS');
                            app.HTML.Data = jsonencode(pref);
                            app.HTML.HTMLSource = '';
                            drawnow
                            app.HTML.HTMLSource = 'Preferences.html';

                            pause(0.1)
                    end
                catch ME
                    % Catch and report error
                    errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.message);
                    ErrorLog(app, errorMessage)
                    app.HTML.HTMLSource = 'Unsuccessful.html';
                end
            elseif app.DeveloperMenu.Checked == true
                Output = jsondecode(app.HTML.Data);
                switch Output.Response
                    case 'Submit'
                        [status]=dev_file_test(app);
                        switch status
                            case 'Cancel'
                                app.HTML.HTMLSource = 'Developer.html';
                            otherwise
                                app.HTML.HTMLSource = 'Success.html';
                        end
                    case 'Restart'
                        app.HTML.HTMLSource = 'Developer.html';
                    case 'Reset'
                        setprefRPSPASS('RPSPASS','dev_path','')
                        uialert(app.RPSPASS,'Developer test directory has been reset','Test directory','Icon','warning')
                end
            end

        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            pref = getprefRPSPASS('RPSPASS');
            app.HTML.Data = jsonencode(pref);
            app.HTML.HTMLSource = 'preferences.html';
        end

        % Menu selected function: UserMenu
        function UserMenuSelected(app, event)
            app.UserMenu.Checked = true;            % create a check for user
            app.DeveloperMenu.Checked = false;      % remove a check for developer
            setprefRPSPASS('RPSPASS','matfile',1);  % turn mat file export on
            setprefRPSPASS('RPSPASS','fcsfile',1);  % turn fcs file export on
            setprefRPSPASS('RPSPASS','csvfile',1);  % turn csv file export on
            app.HTML.HTMLSource = 'StartMenu.html'; % switch to user start menu
        end

        % Menu selected function: DeveloperMenu
        function DeveloperMenuSelected(app, event)
            app.UserMenu.Checked = false;           % remove a check for user
            app.DeveloperMenu.Checked = true;       % create a check for developer
            setprefRPSPASS('RPSPASS','matfile',0);  % turn mat file export off
            setprefRPSPASS('RPSPASS','fcsfile',0);  % turn fcs file export off
            setprefRPSPASS('RPSPASS','csvfile',0);  % turn csv file export off
            app.HTML.HTMLSource = 'Developer.html'; % switch to developer start menu
        end

        % Menu selected function: ErrorlogMenu
        function ErrorlogMenuSelected(app, event)
            % get error log path in hidden directory
            filename = fullfile(getprefRPSPASS('RPSPASS','prefpath'),'RPSPASS Error Log.txt');

            % see if an error log exists in hidden application directory
            if isfile(filename)
                last_dir = getprefRPSPASS('RPSPASS','last_dir');

                % check last open directory still exists
                if isfolder(last_dir)
                    [name,selpath] = uiputfile(fullfile(last_dir,'RPSPASS Error Log.txt'));
                else
                    [name,selpath] = uiputfile();
                end

                % check export locations exists
                if selpath ~= 0
                    copyfile(filename, fullfile(selpath,name))
                    uialert(app.RPSPASS,'Error log exported succesfully.','Error Log Export','Icon','success')
                else
                    uialert(app.RPSPASS,'Error log export failed.','Error Log Export','Icon','error')
                end
            else
                uialert(app.RPSPASS,'There are currently no recorded errors.','Error Log Export','Icon','error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPSPASS = uifigure('Visible', 'off');
            app.RPSPASS.Name = 'RPSPASS';
            app.RPSPASS.Icon = 'RPS Logo.jpg';
            app.Menu = uimenu(app.RPSPASS);
            app.Menu.Text = 'Menu';
            app.PreferencesMenu = uimenu(app.Menu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.ErrorlogMenu = uimenu(app.ExportMenu);
            app.ErrorlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ErrorlogMenuSelected, true);
            app.ErrorlogMenu.Text = 'Error log';
            app.ModeMenu = uimenu(app.Menu);
            app.ModeMenu.Text = 'Mode';
            app.UserMenu = uimenu(app.ModeMenu);
            app.UserMenu.MenuSelectedFcn = createCallbackFcn(app, @UserMenuSelected, true);
            app.UserMenu.Checked = 'on';
            app.UserMenu.Text = 'User';
            app.DeveloperMenu = uimenu(app.ModeMenu);
            app.DeveloperMenu.MenuSelectedFcn = createCallbackFcn(app, @DeveloperMenuSelected, true);
            app.DeveloperMenu.Text = 'Developer';
            app.HTML = uihtml(app.RPSPASS);
            app.HTML.HTMLSource = 'StartMenu.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.RPSPASS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Master

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RPSPASS)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RPSPASS)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPSPASS)
        end
    end
end